classdef Ca_Data_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImageAxes                    matlab.ui.control.UIAxes
        LoadButton                   matlab.ui.control.Button
        AcitvityAxis                 matlab.ui.control.UIAxes
        ImagingChannelDropDownLabel  matlab.ui.control.Label
        ImagingChannelDropDown       matlab.ui.control.DropDown
        DataTypeDropDownLabel        matlab.ui.control.Label
        DataTypeDropDown             matlab.ui.control.DropDown
        PrimaryCndNoEditFieldLabel   matlab.ui.control.Label
        PrimaryCndNoEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        experimentStructure
        cmap
        cellNum
        numberTextHandle
        zoomLevel
        patchCenter
        secondaryCndNo
    end
    
    methods (Access = private)
        
        function loadImage(app)
            
            % load in average image
            try
                displayIm = read_Tiffs([app.experimentStructure.savePath 'STD_Stim_Sum.tif']);
            catch
                displayIm = read_Tiffs([app.experimentStructure.savePath 'STD_Stim_Sum_Ch' app.ImagingChannelDropDown.Value '.tif']);
            end
            
            createPatchFigApp(app, displayIm);
        end
        
        function createPatchFigApp(app, displayIm)
            % find number of ROIs
            
            imageROI = app.experimentStructure.labeledCellROI;
            numROIs = max(imageROI(:));
            boundaries = cell(numROIs,1);
            %iterate through ROI number to get them in appropriate order
            for i = 1: numROIs
                tempImageROI = imageROI;
                tempImageROI(tempImageROI~=i) = 0;
                tempBounds = bwboundaries(tempImageROI, 4, 'noholes');
                boundaries(i,1) =tempBounds(1);
            end
            % sets color map for the cell ROIs
            
            cmap = distinguishable_colors(numROIs+1,'w');
            % cmap = linspecer(numROIs+1, 'qualitative');
            % cmap = cmap(randperm(size(cmap,1)),:);
            cmap(1,:) = [0,0,0];
            app.cmap = cmap;
            
            imagesc(app.ImageAxes,displayIm);
            % build patch structure
            
            for i =1:numROIs
                patch(app.ImageAxes,boundaries{i,1}(:,2), boundaries{i,1}(:,1), app.cmap(i+1, :), 'FaceAlpha', 0.3, ...
                    'ButtonDownFcn', @(src,evnt)plotMouseClickvApp(app,src,evnt));
            end
            
            % check to get primary cnd number
            if isfield(app.experimentStructure, 'stimParams')
                
                try
                    app.PrimaryCndNoEditField.Value = length(unique(app.experimentStructure.stimParams.Angle));
                catch ME
                    app.PrimaryCndNoEditField.Value = length(app.experimentStructure.stimParams.levelStim);
                end
            end 
            
            app.secondaryCndNo = length(app.experimentStructure.cndTotal)/app.PrimaryCndNoEditField.Value;
        end
        
        function plotMouseClickvApp(app,src,evnt)
            % RETRIEVE CLICK LOCATION AND DATA TYPE
            cp = fliplr(evnt.IntersectionPoint(1:2));
            clickPoint = floor(cp);
            
            
            switch evnt.Button
                
                case 1 % Left Button PLOT SINGLE TRACE
                    
                    % figure out which cell the click was on
                    indxClick = sub2ind([app.experimentStructure.pixelsPerLine app.experimentStructure.linesPerFrame], clickPoint(1), clickPoint(2));
                
                    app.cellNum = app.experimentStructure.labeledCellROI(indxClick);
                    
                    if app.cellNum == 0 
                        app.cellNum = 1;
                    end
            end
            
            plotCaTracesApp(app);
            addCellNumberLabel(app);
            
        end
        
        function plotCaTracesApp(app)
            
            
            cla(app.AcitvityAxis);
            vline2(app.AcitvityAxis, 'clear');
            
            switch  app.DataTypeDropDown.Value
                
                case 'FBS: Mean Cnd'
                    data2plot = app.experimentStructure.dFperCndMeanFBS{app.cellNum};
                    lengthOfData = app.experimentStructure.meanFrameLength;
                    
                    % plotting
                    for x =1:size(data2plot, 2)
                        
                        % get postion along X axis
                        if x >1
                            spacing = 5;
                            xlocations(x,:) = ((lengthOfData +lengthOfData* (x-1))- (lengthOfData-1) :lengthOfData + lengthOfData* (x-1)) + spacing*(x-1);
                            xlocationMid(x) = xlocations(x,round(lengthOfData/2));
                        else
                            spacing = 0;
                            xlocations(x,:) = 1:lengthOfData;
                            xlocationMid(x) = xlocations(x,round(lengthOfData/2));
                        end
                        
                        
                        lineCol = app.cmap(app.cellNum+1, :);
                        
                        errorBars = app.experimentStructure.dFperCndSTDFBS{app.cellNum}(:,x);
                        shadedErrorBarV2(xlocations(x,:),data2plot(:,x),errorBars, 'lineprops', {'-','Color', lineCol, 'LineWidth', 2},'Axis', app.AcitvityAxis);
                        
                        yMaxVector(x) =  max(data2plot(:,x)+errorBars);
                        yMinVector(x) =  min(data2plot(:,x)-errorBars);
                        
                    end
                    
                   app.AcitvityAxis.YLim = [min(yMinVector)-0.2 max(yMaxVector)+0.2];
                    
                    hold(app.AcitvityAxis, 'on');
                    
                    % plot stim on/ off lines
                    
                    for x =1:size(data2plot, 2)
                        vline2(app.AcitvityAxis, xlocations(x,1)+ app.experimentStructure.stimOnFrames(1)-1, {'k--'});
                        vline2(app.AcitvityAxis, xlocations(x,1)+ app.experimentStructure.stimOnFrames(2)-1, {'k--'});
                    end
                    close gcf
                    
                    OSI_text =[];
                    if isfield(app.experimentStructure,'OSI_FBS')
                        OSI_text = num2str(app.experimentStructure.OSI_FBS(app.cellNum));
                    end
                    
                    app.AcitvityAxis.XTick = xlocationMid;
                    app.AcitvityAxis.XTickLabel = [1:size(data2plot, 2)];
                    app.AcitvityAxis.XLim = [0, max(xlocations(:))]; 
                    app.AcitvityAxis.Title.String = ['FBS: Mean dF/F Cnd   OSI: ' OSI_text ];
                    app.AcitvityAxis.YLabel.String = '\DeltaF/F';
                    app.AcitvityAxis.XLabel.String = 'Cnd No.';
                    
                case 'Raw'
                    data2plot = app.experimentStructure.rawF(app.cellNum,:);
                    
                    plot( app.AcitvityAxis,1:length(data2plot),data2plot, 'Color',app.cmap(app.cellNum+1, :));
                    close gcf;
                    app.AcitvityAxis.YLim = [min(data2plot) - 100 ; max(data2plot) + 100  ];
                    app.AcitvityAxis.XLim = [0 length(data2plot)];
                    app.AcitvityAxis.XTick = [0:250:length(data2plot) ];
                    app.AcitvityAxis.XTickLabel = [0:250:length(data2plot) ];
                    app.AcitvityAxis.Title.String = 'Raw F';
                    app.AcitvityAxis.YLabel.String = 'Intensity';
                    app.AcitvityAxis.XLabel.String = 'Frame No.';
                    
                case 'Neuropil Corrected: Mean Cnd'
                    data2plot = app.experimentStructure.dFperCndMean{app.cellNum};
                    
                    lengthOfData = app.experimentStructure.meanFrameLength;
                    
                    % plotting
                    for x =1:size(data2plot, 2)
                        
                        % get postion along X axis
                        if x >1
                            spacing = 5;
                            xlocations(x,:) = ((lengthOfData +lengthOfData* (x-1))- (lengthOfData-1) :lengthOfData + lengthOfData* (x-1)) + spacing*(x-1);
                            xlocationMid(x) = xlocations(x,round(lengthOfData/2));
                        else
                            spacing = 0;
                            xlocations(x,:) = 1:lengthOfData;
                            xlocationMid(x) = xlocations(x,round(lengthOfData/2));
                        end
                        
                        
                        lineCol = app.cmap(app.cellNum+1, :);
                        
                        errorBars = app.experimentStructure.dFperCndSTD{app.cellNum}(:,x);
                        shadedErrorBarV2(xlocations(x,:),data2plot(:,x),errorBars, 'lineprops', {'-','Color', lineCol, 'LineWidth', 2},'Axis', app.AcitvityAxis);
                        
                        yMaxVector(x) =  max(data2plot(:,x)+errorBars);
                        yMinVector(x) =  min(data2plot(:,x)-errorBars);
                        
                    end
                    
                   app.AcitvityAxis.YLim = [min(yMinVector)-0.2 max(yMaxVector)+0.2];
                    
                    hold(app.AcitvityAxis, 'on');
                    
                    % plot stim on/ off lines
                    
                    for x =1:size(data2plot, 2)
                        vline2(app.AcitvityAxis, xlocations(x,1)+ app.experimentStructure.stimOnFrames(1)-1, {'k--'});
                        vline2(app.AcitvityAxis, xlocations(x,1)+ app.experimentStructure.stimOnFrames(2)-1, {'k--'});
                    end
                    close gcf
                    
                    OSI_text =[];
                    if isfield(app.experimentStructure,'OSI_Neuro_corr')
                        OSI_text = num2str(app.experimentStructure.OSI(app.cellNum));
                    end
                    
                    app.AcitvityAxis.XTick = xlocationMid;
                    app.AcitvityAxis.XTickLabel = [1:size(data2plot, 2)];
                    app.AcitvityAxis.XLim = [0, max(xlocations(:))]; 
                    app.AcitvityAxis.Title.String = ['Neuropil Corrected: Mean dF/F Cnd   OSI: ' OSI_text ];
                    app.AcitvityAxis.YLabel.String = '\DeltaF/F';
                    app.AcitvityAxis.XLabel.String = 'Cnd No.';
                    
                case 'Neuropil Corrected: dF/F'
                    data2plot = app.experimentStructure.dF(app.cellNum,:);
                    
                    plot( app.AcitvityAxis,1:length(data2plot),data2plot, 'Color',app.cmap(app.cellNum+1, :));
                    close gcf
                   
                    app.AcitvityAxis.YLim = [min(data2plot) - 0.1; max(data2plot) + 0.1];
                    app.AcitvityAxis.XLim = [0 length(data2plot)];
                    app.AcitvityAxis.XTick = [0:250:length(data2plot) ];
                    app.AcitvityAxis.XTickLabel = [0:250:length(data2plot) ];
                    app.AcitvityAxis.Title.String = 'Neuropil Corrected: dF/F';
                    app.AcitvityAxis.YLabel.String = '\DeltaF/F';
                    app.AcitvityAxis.XLabel.String = 'Frame No.'; 
            end
        end
        
        function addCellNumberLabel(app)
            delete(app.numberTextHandle);
            % plots new cell number
            app.numberTextHandle = handle(text(...
                'String', sprintf('%i',app.cellNum),...
                'FontSize', 20, ...
                'FontWeight','bold',...
                'BackgroundColor',[.1 .1 .1 .3],...
                'Margin',1,...
                'Position', round([app.experimentStructure.xPos(app.cellNum) app.experimentStructure.yPos(app.cellNum)]) - [0 10],...
                'Parent', app.ImageAxes,...
                'Color',app.cmap(app.cellNum+1, :)));
            
            % if the magnifcation factor is greater than 1, then shifts cell
            % image view to centralize current cell
            if app.zoomLevel >1
                   shiftImageView(app)
            end
        end
        
        function magnifyImage(app)
            if app.zoomLevel >1
                app.patchCenter = [app.experimentStructure.xPos(app.cellNum) app.experimentStructure.yPos(app.cellNum)];
            else
                app.patchCenter = [app.experimentStructure.pixelsPerLine/2 app.experimentStructure.pixelsPerLine/2];
            end

            newBoundary = round(app.experimentStructure.pixelsPerLine/ app.zoomLevel); % sets new frame size
            
            app.ImageAxes.YLim = [(app.patchCenter(2)-newBoundary) (app.patchCenter(2)+newBoundary)];
            app.ImageAxes.XLim = [(app.patchCenter(1)-newBoundary) (app.patchCenter(1)+newBoundary)];
        end
        
        function shiftImageView(app)
            axisLims = app.ImageAxes.XLim; % gets current axis limits
            newBoundary = round((axisLims(2)-axisLims(1))/2); % gets current view size in pixels
            
            % sets new axis size around patch center
            app.ImageAxes.XLim = [app.experimentStructure.xPos(app.cellNum)-newBoundary app.experimentStructure.xPos(app.cellNum)+newBoundary];
            app.ImageAxes.YLim = [app.experimentStructure.yPos(app.cellNum)-newBoundary app.experimentStructure.yPos(app.cellNum)+newBoundary];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % maximise screen size
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
            
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.ImagingChannelDropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                load([p f]);
                app.experimentStructure = experimentStructure;
                loadImage(app);
            end
        end

        % Value changed function: ImagingChannelDropDown
        function ImagingChannelDropDownValueChanged(app, event)
            app.ImagingChannelDropDown.Value;
            loadImage(app);
            
            
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            plotCaTracesApp(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

            if isempty(app.cellNum)
                app.cellNum = 1;
            end
            
            switch event.Key
                case 'space' % CHOOSE TRACES TO PLOT
                    % Sets up dialog box
                    prompt = {'Enter desired trace numbers:'};
                    dlg_title = 'Trace No. Inputs';
                    num_lines = 1;
                    defaultans = {'1'};
                    answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
                    answer = str2num(answer{1,1});
                    app.cellNum = answer;
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                case 'leftarrow' % CYCLE DOWN THROUGH TRACES
                    app.cellNum=app.cellNum-1; % iterates down one cell
                    
                    if app.cellNum<1 % unless cell is 1
                        app.cellNum= 1;
                    end
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                    
                case 'rightarrow' %CYCLE UP THROUGH TRACES
                    app.cellNum=app.cellNum+1; % iterates down one cell
                    
                    if app.cellNum >=app.experimentStructure.cellCount% unless cell is 1
                        app.cellNum= 1;
                    end
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                    
                    
                case 'uparrow' %INCREASE IMAGE ZOOM
                    app.zoomLevel = 3;
                    magnifyImage(app);
                case 'downarrow' % DECREASE IMAGE ZOOM
                    app.zoomLevel = 1;
                    magnifyImage(app);
            end
        end

        % Value changed function: PrimaryCndNoEditField
        function PrimaryCndNoEditFieldValueChanged(app, event)
            app.PrimaryCndNoEditField.Value;
            app.secondaryCndNo = length(app.experimentStructure.cndTotal)/app.PrimaryCndNoEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Experiment Structure';
            app.AcitvityAxis = uiaxes(app.UIFigure);
            title(app.AcitvityAxis, '')
            xlabel(app.AcitvityAxis, '')
            ylabel(app.AcitvityAxis, '')
            app.AcitvityAxis.XLim = [0 255];
            app.AcitvityAxis.XTick = [0 128 255];
            app.ImagingChannelDropDownLabel = uilabel(app.UIFigure);
            app.ImagingChannelDropDownLabel.Text = 'Imaging Channel ';
            app.ImagingChannelDropDown = uidropdown(app.UIFigure);
            app.ImagingChannelDropDown.Items = {'1', '2'};
            app.ImagingChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingChannelDropDownValueChanged, true);
            app.ImagingChannelDropDown.Value = '2';
            app.DataTypeDropDownLabel = uilabel(app.UIFigure);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'FBS: Mean Cnd', 'Raw', 'Neuropil Corrected: Mean Cnd', 'Neuropil Corrected: dF/F'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'FBS: Mean Cnd';
            app.PrimaryCndNoEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryCndNoEditFieldLabel.Text = 'Primary Cnd No.';
            app.PrimaryCndNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryCndNoEditField.ValueChangedFcn = createCallbackFcn(app, @PrimaryCndNoEditFieldValueChanged, true);
            app.PrimaryCndNoEditField.Value = 8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ca_Data_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end